classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        WithDrawButton         matlab.ui.control.Button
        DepositButton          matlab.ui.control.Button
        BalanceEditFieldLabel  matlab.ui.control.Label
        ViewBalance            matlab.ui.control.NumericEditField
        RMBEditFieldLabel      matlab.ui.control.Label
        ViewRMB                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Balance double % 余额
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Balance = 500;
            app.ViewRMB.Value = 0 ;
            app.ViewBalance.Value = app.Balance;
        end

        % Button pushed function: WithDrawButton
        function WithDrawButtonPushed(app, event)
            value = app.ViewRMB.Value;
            app.Balance = app.Balance - value;
            app.ViewBalance.Value = app.Balance;
        end

        % Button pushed function: DepositButton
        function DepositButtonPushed(app, event)
            value = app.ViewRMB.Value;
            app.Balance = app.Balance + value;
            app.ViewBalance.Value = app.Balance;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WithDrawButton = uibutton(app.UIFigure, 'push');
            app.WithDrawButton.ButtonPushedFcn = createCallbackFcn(app, @WithDrawButtonPushed, true);
            app.WithDrawButton.Text = 'WithDraw';
            app.DepositButton = uibutton(app.UIFigure, 'push');
            app.DepositButton.ButtonPushedFcn = createCallbackFcn(app, @DepositButtonPushed, true);
            app.DepositButton.Text = 'Deposit';
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.ViewBalance = uieditfield(app.UIFigure, 'numeric');
            app.ViewBalance.Value = 500.5;
            app.RMBEditFieldLabel = uilabel(app.UIFigure);
            app.RMBEditFieldLabel.Text = 'RMB';
            app.ViewRMB = uieditfield(app.UIFigure, 'numeric');
            app.ViewRMB.Value = 50.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end